classdef BoardPlayingRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        CollisionsSwitchLabel     matlab.ui.control.Label
        CollisionsSwitch          matlab.ui.control.Switch
        YSpinnerLabel             matlab.ui.control.Label
        YSpinner                  matlab.ui.control.Spinner
        XSpinnerLabel             matlab.ui.control.Label
        XSpinner                  matlab.ui.control.Spinner
        ZSpinnerLabel             matlab.ui.control.Label
        ZSpinner                  matlab.ui.control.Spinner
        StartGameButton           matlab.ui.control.Button
        q1SliderLabel             matlab.ui.control.Label
        q1Slider                  matlab.ui.control.Slider
        q2SliderLabel             matlab.ui.control.Label
        q2Slider                  matlab.ui.control.Slider
        q3SliderLabel             matlab.ui.control.Label
        q3Slider                  matlab.ui.control.Slider
        q4SliderLabel             matlab.ui.control.Label
        q4Slider                  matlab.ui.control.Slider
        ChoosemoveLabel           matlab.ui.control.Label
        Button_1                  matlab.ui.control.StateButton
        Button_2                  matlab.ui.control.StateButton
        Button_3                  matlab.ui.control.StateButton
        Button_4                  matlab.ui.control.StateButton
        Button_5                  matlab.ui.control.StateButton
        Button_6                  matlab.ui.control.StateButton
        Button_7                  matlab.ui.control.StateButton
        Button_8                  matlab.ui.control.StateButton
        Button_9                  matlab.ui.control.StateButton
        EndGameButton             matlab.ui.control.Button
        EmergencyStopSwitchLabel  matlab.ui.control.Label
        EmergencyStopSwitch       matlab.ui.control.Switch
    end


    properties (Access = private)
        uArm = uArmRobot();
        emergency = false;
        collisions = false;
        qLimits = [-pi/2,pi/2; (-1.5/3*pi),(1.5/3*pi); -pi/3,(2/3*pi); -2*pi, 2*pi/3];
        sliders;
        slidersLabels;
        spinners;
        spinnersLabels;
        boardSize = 3;
        board = TicTacToeBoard(3);
        tokens;
        buttons;
        slidersLabelsValues = {'q1', 'q2', 'q3', 'q4'};
        spinnersLabelsValues = {'X', 'Y', 'Z'};
        
        trials = 5000;
    end

    methods (Access = private)
        
        % Function that set the default sliders properties
        function setSliders(app)
            app.sliders = {app.q1Slider, app.q2Slider, app.q3Slider, app.q4Slider};
            app.slidersLabels = {app.q1SliderLabel, app.q2SliderLabel, ...
                                 app.q3SliderLabel, app.q4SliderLabel};
            qLimitsDeg = rad2deg(app.qLimits);
            
            for i = 1:length(qLimitsDeg)
                qMajorTickStep = (qLimitsDeg(i, 2) - qLimitsDeg(i, 1)) / 4;
                
                set(app.sliders{i}, 'Limits', qLimitsDeg(i, :));
                set(app.sliders{i}, 'MajorTicks', qLimitsDeg(i, 1):qMajorTickStep:qLimitsDeg(i, 2));
            end
            
            set(app.q2Slider, 'MajorTickLabels', {'-180', '-120', '-60', '0', '60', '120'});
            
        end
        
        % Function that changes the sliders values accordingly to the robot joint positions
        function setSlidersValues(app, qValues)
            for idx = 1:length(qValues)
                app.sliders{idx}.Value = qValues(idx);
            end
        end
        
        % Function that set the default spinner values
        function setSpinners(app, qValues)
            endEffector = app.uArm.getEndEffector(qValues);
            
            app.spinners = {app.XSpinner, app.YSpinner, app.ZSpinner};
            app.spinnersLabels = {app.XSpinnerLabel, app.YSpinnerLabel, app.ZSpinnerLabel};
            app.setSpinnersValues(endEffector);
            
        end
        
        % Function to set the spinners values accordingly to the robot current end effector position
        function setSpinnersValues(app, endEffector)
            for i = 1:length(app.spinners)
                pos = endEffector(i, 4);
                set(app.spinners{i}, 'Value', pos);
            end
        end
        
        % Function that set the buttons to choose the move
        function setButtons(app)
            app.buttons = {app.Button_1, app.Button_2, app.Button_3, ...
                           app.Button_4, app.Button_5, app.Button_6, ...
                           app.Button_7, app.Button_8, app.Button_9};
            app.buttonsVisibility('off');
        end
        
        % Function that hides or unhides the choose move buttons matrix
        function buttonsVisibility(app, visible)
            for i = 1:length(app.buttons)
                set(app.buttons{i}, 'Visible', visible);
                
                if strcmp(visible, 'on')
                    set(app.buttons{i}, 'Value', 0);
                end
            end
            set(app.ChoosemoveLabel, 'Visible', visible);
        end  
        
        % Function that sets the figure to plot the board
        function setUIAxes(app)
            set(app.UIAxes, 'XLim', [0 1.2]);
            set(app.UIAxes, 'YLim', [0 1.2]);
            app.UIAxes.Title.String = 'Tic Tac Toe';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Box = 'on';
            app.UIAxes.Position(3) = 375;
            app.UIAxes.Position(4) = 375;
            
            app.plotTicTacToe();
            app.createTokens();
        end
        
        % Plots the robot to the given qValues
        function plot(app, qValues)
            app.uArm.plot(qValues);
        end
        
        % Plots the Tic Tac Toe lines in the figure
        function plotTicTacToe(app)
            lineLimits = [0 1.2];
            
            hold(app.UIAxes, 'on');
            for i = 0.4:0.4:0.8
                plot(app.UIAxes, lineLimits, [i i], 'k');
                plot(app.UIAxes, [i i], lineLimits, 'k');
            end
            hold(app.UIAxes, 'off');
        end
        
        % Function that creates the tokens in the figure with a white color so they are not visible
        function createTokens(app)
            hold(app.UIAxes, 'on');
            for row = 1:app.boardSize
                for col = 1:app.boardSize
                    position = [(col * 0.4 - 0.4) (row * 0.4 - 0.4) 0.4 0.4];
                    app.tokens(row, col) = rectangle(app.UIAxes, 'Position', position, ...
                                                     'Curvature', [1 1], 'FaceColor', 'none', ...
                                                     'EdgeColor', 'none');
                end
            end
            hold(app.UIAxes, 'off');
        end
        
        % Function that deletes the color of the tokens in the figure
        function eraseTokens(app)
            for row = 1:app.boardSize
                for col = 1:app.boardSize
                    set(app.tokens(row, col), 'FaceColor', 'none');
                end
            end
        end
        
        % Function that changes the color of the token at row, col to the one used by the current player
        function plotToken(app, row, col)
            if app.board.getCurrentPlayer() == app.board.PLAYER_X
                color = 'k';
            else
                color = 'c';
            end
        
            set(app.tokens(row, col), 'FaceColor', color);
        end
        
        % Function that plots a move and checks for a winner
        function plotMove(app, row, col)
            app.board = app.board.move(row, col);
            app.board = app.board.changePlayer();
            app.plotToken(row, col);
            
            player = app.board.getCurrentPlayer();
            
            if ~app.board.checkWinner()
                set(app.ChoosemoveLabel, 'text', strcat('Is player', num2str(player), ' turn'));
            end
        end
        
        % Function to handle when the player makes a move
        function playerMove(app, row, col)
            if app.board.isSqrEmpty(row, col)
                app.uArm.makeMove(row, col, app.board.getCurrentPlayer(), app.board);
                app.plotMove(row, col);
                
                if ~app.board.checkWinner()
                    app.robotMove();
                end
                
                if app.board.checkWinner() && app.board.checkWinner() ~= app.board.DRAW
                    set(app.ChoosemoveLabel, 'text', strcat('Player', num2str(app.board.getCurrentPlayer), ' won!!!'));
                elseif app.board.checkWinner()
                    set(app.ChoosemoveLabel, 'text', strcat('Its a draw'));
                end
                
            end
        end
        
        % Function that hides all the position controls for the robot while a game is in progress
        function controlVisibility(app, visible)
            
            for i = 1:length(app.sliders)
                set(app.sliders{i}, 'Visible', visible);
                
                if strcmp(visible, 'on')
                    text = app.slidersLabelsValues{i};
                else
                    text = '';
                end
                
                set(app.slidersLabels{i}, 'Text', text);
            end
            
            for i = 1:length(app.spinners)
                set(app.spinners{i}, 'Visible', visible);
                
                if strcmp(visible, 'on')
                    text = app.spinnersLabelsValues{i};
                else
                    text = '';
                end
                
                set(app.spinnersLabels{i}, 'Text', text);
            end
        end
        
        % Function that enables the robot to make a move
        function robotMove(app)
            move = app.computeMove(app.board);
            app.uArm.makeMove(move(1), move(2), app.board.getCurrentPlayer(), app.board);
            app.plotMove(move(1), move(2));
        end
        
        % Function that chooses a move for the robot using a Monte Carlo algorithm approach
        function move = computeMove(app, currentBoard)
            
            % Function that makes a random game
            function board = trialGame(board)
                emptySqrs = board.getEmptySqrs();
                moves = datasample(emptySqrs, length(emptySqrs), 'Replace', false);
                idx = 1;
                
                winner = board.checkWinner();
                
                while ~winner
                    board = board.move(moves(idx, 1), moves(idx, 2));
                    board = board.changePlayer();
                    idx = idx + 1;
                    winner = board.checkWinner();
                end
                
            end
            
            % Updates each square score depending on the result of the last random game given by board
            function scores = updateScores(scores, board, player)
                winner = board.checkWinner();
                
                
                if winner ~= board.DRAW
                    playerWins = 1;
                    playerLoses = -1;
                    
                    if winner ~= player
                        playerWins = -playerWins;
                        playerLoses = -playerLoses;
                    end
                    
                    boardSize = board.boardSize;
                    for row = 1:boardSize
                        for col = 1:boardSize
                            if player == board.board(row, col)
                                scores(row, col) = scores(row, col) + playerWins;
                            elseif board.EMPTY ~= board.board(row, col)
                                scores(row, col) = scores(row, col) - playerLoses;
                            end
                        end
                    end
                end
            end
            
            % Function that chooses the move which has the greater odds of winning
            function bestMove = getBestMove(board)
                emptySqrs = board.getEmptySqrs();
                
                maximum = -100000000;
                
                for idx = 1:length(emptySqrs)
                    empty = emptySqrs(idx, :);
                    if scores(empty(1), empty(2)) > maximum
                        maximum = scores(empty(1), empty(2));
                    end
                end
                
                possibleMoves = [];
                for idx = 1:length(emptySqrs)
                    empty = emptySqrs(idx, :);
                    
                    if scores(empty(1), empty(2)) == maximum
                        possibleMoves = [possibleMoves; empty];
                    end
                end
                
                if 1 < size(possibleMoves, 1)
                    bestMove = datasample(possibleMoves, 1);
                else
                    bestMove = possibleMoves;
                end
            end
            
            
            scores = zeros(app.board.boardSize, app.board.boardSize);
            
            for idx = 1:app.trials
                newBoard = app.board;
                
                newBoard = trialGame(newBoard);
                scores = updateScores(scores, newBoard, app.board.getCurrentPlayer());
            end
            
            move = getBestMove(app.board);
            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setSliders();
            app.setSpinners([0 0 0 0]);
            app.setUIAxes();
            app.setButtons();
        end

        % Value changed function: Button_1
        function Button1ValueChanged(app, event)
            value = app.Button_1.Value;
            row = 1;
            col = 1;
            
            if value
                app.playerMove(row, col);
            end
            
        end

        % Value changed function: Button_2
        function Button2ValueChanged(app, event)
            value = app.Button_2.Value;
            row = 1;
            col = 2;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_3
        function Button3ValueChanged(app, event)
            value = app.Button_3.Value;
            row = 1;
            col = 3;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_4
        function Button4ValueChanged(app, event)
            value = app.Button_4.Value;
            row = 2;
            col = 1;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_5
        function Button5ValueChanged(app, event)
            value = app.Button_5.Value;
            row = 2;
            col = 2;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_6
        function Button6ValueChanged(app, event)
            value = app.Button_6.Value;
            row = 2;
            col = 3;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_7
        function Button7ValueChanged(app, event)
            value = app.Button_7.Value;
            row = 3;
            col = 1;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_8
        function Button8ValueChanged(app, event)
            value = app.Button_8.Value;
            row = 3;
            col = 2;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: Button_9
        function Button9ValueChanged(app, event)
            value = app.Button_9.Value;
            row = 3;
            col = 3;
            
            if value
                app.playerMove(row, col);
            end
        end

        % Value changed function: CollisionsSwitch
        function CollisionsSwitchValueChanged(app, event)
            value = app.CollisionsSwitch.Value;
            
            if strcmp(value, 'On')
                display('Collissions have been activated');
                app.uArm.collisionPoint = [20 0 10];
            else
                display('Collissions have been deactivated');
                app.uArm.collisionPoint = [-50 -50 -50];
            end
                
        end

        % Callback function
        function ContinueButtonPushed(app, event)
            app.uArm.iseStop(true);
        end

        % Callback function
        function EmergencyStopSwitchValueChanged(app, event)
            value = app.EmergencyStopSwitch.Value;
            
            if strcmp(value, 'On')
                display('Emergency stop has been activated');
                app.uArm.eStop(1,4) = 1;
            else
                display('Emergency stop has been deactivated');
                app.uArm.eStop(1,4) = 0;
            end
        end

        % Button pushed function: EndGameButton
        function EndGameButtonPushed(app, event)
            app.buttonsVisibility('off');
            app.eraseTokens();
            app.controlVisibility('on');
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.buttonsVisibility('on');
            app.controlVisibility('off');
            app.board = app.board.resetBoard();
        end

        % Value changed function: q1Slider, q2Slider, q3Slider, 
        % q4Slider
        function sliderValueChanged(app, event)
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            
            qValues = deg2rad([q1 q2 q3 q4])
            
            app.plot(qValues);
            app.setSpinnersValues(app.uArm.getEndEffector(qValues));
            
        end

        % Value changed function: XSpinner, YSpinner, ZSpinner
        function spinnerValueChanged(app, event)
            goalXYZ = [app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value];
            app.uArm.moveTo(goalXYZ);
            qValues = app.uArm.getPos();
            app.setSlidersValues(rad2deg(qValues));
            qValues = rad2deg(qValues);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CollisionsSwitchLabel = uilabel(app.UIFigure);
            app.CollisionsSwitchLabel.Text = 'Collisions';
            app.CollisionsSwitch = uiswitch(app.UIFigure, 'slider');
            app.CollisionsSwitch.ValueChangedFcn = createCallbackFcn(app, @CollisionsSwitchValueChanged, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @spinnerValueChanged, true);
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @spinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @spinnerValueChanged, true);
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.ChoosemoveLabel = uilabel(app.UIFigure);
            app.ChoosemoveLabel.Text = 'Choose move';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button3ValueChanged, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button4ValueChanged, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button5ValueChanged, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button6ValueChanged, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button7ValueChanged, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button8ValueChanged, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button9ValueChanged, true);
            app.Button_9.Text = '';
            app.EndGameButton = uibutton(app.UIFigure, 'push');
            app.EndGameButton.ButtonPushedFcn = createCallbackFcn(app, @EndGameButtonPushed, true);
            app.EndGameButton.Text = 'End Game';
            app.EmergencyStopSwitchLabel = uilabel(app.UIFigure);
            app.EmergencyStopSwitchLabel.Text = 'Emergency Stop';
            app.EmergencyStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EmergencyStopSwitch.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BoardPlayingRobot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end